classdef modelisation_lidar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MODLISATIONLIDARBAKKACHJAMALIUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SGMENTATIONRANSACTab            matlab.ui.container.Tab
        TOLRANCEKnobLabel               matlab.ui.control.Label
        TOLRANCEKnob                    matlab.ui.control.Knob
        NOMBREMINIMUMDEPOINTSKnobLabel  matlab.ui.control.Label
        NOMBREMINIMUMDEPOINTSKnob       matlab.ui.control.DiscreteKnob
        ACCEPTABLEPOURUNPLANLabel       matlab.ui.control.Label
        EXCUTERButton                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LabelPlansPoints                matlab.ui.control.Label
        SegRansacAnnulerButton          matlab.ui.control.Button
        DTCTIONDESCONTOURSTab           matlab.ui.container.Tab
        ExecDetectContours              matlab.ui.control.Button
        AnnulerDetectContours           matlab.ui.control.Button
        DTCTIONDESDROITESTab            matlab.ui.container.Tab
        ExecDetectDroites               matlab.ui.control.Button
        ACCEPTABLEPOURUNEDROITELabel    matlab.ui.control.Label
        TOLRANCEKnob_2Label             matlab.ui.control.Label
        ToleranceDroite                 matlab.ui.control.Knob
        NOMBREMINIMUMDEPOINTSKnob_2Label  matlab.ui.control.Label
        MinPtsDroite                    matlab.ui.control.DiscreteKnob
        AnnulerDetectDroites            matlab.ui.control.Button
        POINTSDINTERSECTIONSTab         matlab.ui.container.Tab
        TOLRANCEKnob_2Label_2           matlab.ui.control.Label
        ToleranceIntersection           matlab.ui.control.Knob
        ExecDetectIntersection          matlab.ui.control.Button
        AnnulerDetectIntersection       matlab.ui.control.Button
        CONSTRUCTIONDESPOLYGONESTab     matlab.ui.container.Tab
        ExecConstPoly                   matlab.ui.control.Button
        AnnulerConstPoly                matlab.ui.control.Button
        IMPORTERUNNUAGEDEPOINTSButton   matlab.ui.control.Button
        CheminEditField                 matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Définition des méthodes statiques de traitement
    methods (Static)
        
        function r = iscolinear(p1, p2, p3, flag)
            
            if nargin == 3
               	flag = 'inhomog';
            end
            
            if ~all(size(p1)==size(p2)) | ~all(size(p1)==size(p3)) | ...
                    ~(length(p1)==2 | length(p1)==3)
                error('les points doivent avoir la même dimension de 2 ou 3');
            end
            
            if length(p1) == 2
                p1(3) = 1; p2(3) = 1; p3(3) = 1;
            end
            
            if flag(1) == 'h'
               	r =  abs(dot(cross(p1, p2),p3)) < eps;
            else
               	r =  norm(cross(p2-p1, p3-p1)) < eps;
            end
        end
        
        function B = fitplane(XYZ)
            
            [rows,npts] = size(XYZ);
            
            if rows ~=3
                error('les données ne sont pas en 3D');
            end
            
            if npts < 3
                error('trop peu de points pour correspondre au plan');
            end
            
            A = [XYZ' ones(npts,1)];
            
            if npts == 3
                A = [A; zeros(1,4)];
            end
            
            [u d v] = svd(A);
            B = v(:,4);
        end
        
        function [M, inliers] = ransac(x, fittingfn, distfn, degenfn, s, t, feedback, ...
                maxDataTrials, maxTrials)
            
            error ( nargchk ( 6, 9, nargin ) );
            
            if nargin < 9; maxTrials = 1000;    end;
            if nargin < 8; maxDataTrials = 100; end;
            if nargin < 7; feedback = 0;        end;
            
            [rows, npts] = size(x);
            
            p = 0.99;
            
            bestM = NaN;
            trialcount = 0;
            bestscore =  0;
            N = maxTrials;
            
            while N > trialcount
                
                degenerate = 1;
                count = 1;
                while degenerate
                    if ~exist('randsample', 'file')
                        ind = randomsample(npts, s);
                    else
                        ind = randsample(npts, s);
                    end
                    
                    degenerate = feval(degenfn, x(:,ind));
                    
                    if ~degenerate
                        M = feval(fittingfn, x(:,ind));
                        
                        if isempty(M)
                            degenerate = 1;
                        end
                    end
                    
                    count = count + 1;
                    if count > maxDataTrials
                        warning('Impossible de sélectionner un jeu de données non dégénéré');
                        break
                    end
                end
                
                [inliers, M] = feval(distfn, M, x, t);
                
                ninliers = length(inliers);
                
                if ninliers > bestscore
                    bestscore = ninliers;
                    bestinliers = inliers;
                    bestM = M;
                    
                    fracinliers =  ninliers/npts;
                    pNoOutliers = 1 -  fracinliers^s;
                    pNoOutliers = max(eps, pNoOutliers); 
                    pNoOutliers = min(1-eps, pNoOutliers);
                    N = log(1-p)/log(pNoOutliers);
                end
                
                trialcount = trialcount+1;
                if feedback
                    fprintf('essai %d de %d         \r',trialcount, ceil(N));
                end
                
                % Safeguard against being stuck in this loop forever
                if trialcount > maxTrials
                    warning( ...
                        sprintf('ransac a atteint le nombre maximum de %d essais',...
                        maxTrials));
                    break
                end
            end
            
            if feedback, fprintf('\n'); end
            
            if ~isnan(bestM)   % We got a solution
                M = bestM;
                inliers = bestinliers;
            else
                M = [];
                inliers = [];
                warning('ransac était incapable de trouver une solution utile');
            end
        end
        
        function [B, P, inliers] = ransacfitplane(XYZ, t, feedback)
            
            if nargin == 2
               	feedback = 0;
            end
            
            [rows, npts] = size(XYZ);
            
            if rows ~=3
                error('les données ne sont pas en 3D');
            end
            
            if npts < 3
                error('trop peu de points pour correspondre au plan');
            end
            
            s = 3;
            
            fittingfn = @defineplane;
            distfn    = @planeptdist;
            degenfn   = @isdegenerate;
            
            [P, inliers] = modelisation_lidar.ransac(XYZ, fittingfn, distfn, degenfn, s, t, feedback);
            
            B = modelisation_lidar.fitplane(XYZ(:,inliers));
            
            
            function P = defineplane(X)
                P = X;
            end    
            function [inliers, P] = planeptdist(P, X, t)
                
                n = cross(P(:,2)-P(:,1), P(:,3)-P(:,1)); 
                n = n/norm(n);                           
                
                npts = length(X);
                d = zeros(npts,1);
                
                for i=1:3
                   	d = d + (X(i,:)'-P(i,1))*n(i);
                end
                
                inliers = find(abs(d) < t);
                
            end     
            function r = isdegenerate(X)
                r = modelisation_lidar.iscolinear(X(:,1),X(:,2),X(:,3));
            end
        end
    
        function L = fitline3d(XYZ)
            XYZ = XYZ';
            
            mu = mean(XYZ, 1);
            
            C = cov(XYZ);
            
            [V, D] = eig(C);
            
            col = size(V, 2);  
            
            eVec = V(:, col);
            eVal = D(col, col);
            
            L(:, 1) = mu' - sqrt(eVal)*eVec;
            L(:, 2) = mu' + sqrt(eVal)*eVec;
        end
    
        function [V, L, inliers] = ransacfitline(XYZ, t, feedback)
            
            if nargin == 2
               	feedback = 0;
            end
            
            [rows, npts] = size(XYZ);
            
            if rows ~=3
                error('les données ne sont pas en 3D');
            end
            
            if npts < 2
                error('trop peu de points pour correspondre à la ligne');
            end
            
            s = 2;
            
            fittingfn = @defineline;
            distfn    = @lineptdist;
            degenfn   = @isdegenerate;
            
            [L, inliers] = modelisation_lidar.ransac(XYZ, fittingfn, distfn, degenfn, s, t, feedback);
            
            V = modelisation_lidar.fitline3d(XYZ(:, inliers));
            
            
            function L = defineline(X)
                L = X;
            end
            
            function [inliers, L] = lineptdist(L, X, t)
                
                p1 = L(:,1);
                p2 = L(:,2);
                
                npts = length(X);
                d = zeros(npts, 1);
                
                for i = 1:npts
                    p3 = X(:,i);
                    
                    lambda = dot((p2 - p1), (p2-p3)) / dot( (p1-p2), (p1-p2) );
                    
                    d(i) = norm(lambda*p1 + (1-lambda)*p2 - p3);
                end
                
                inliers = find(abs(d) < t);
            end
            
            function r = isdegenerate(X)
                r = norm(X(:,1) - X(:,2)) < eps;
            end
        end
    
        function [pts] = aplatir_points_barycentre(m)
            [coeff,score] = pca(m);
            % deux premiers vecteurs constituent la base du plan moyen
            base = coeff(:,1:2);
            n = size(m,1);
            % barycentre de pts_3D
            bar = mean(m,1);
            % coordonnées des points dans le plan moyan passant par la barycentre
            pts = repmat(bar,n,1) + score(:,1:2)*base';
        end
    
        function [d, pt1, pt2] = distanceLines3d(line1, line2)
            n1 = size(line1, 1);
            n2 = size(line2, 1);
            if nargout <= 1
                v1x = repmat(line1(:,4), [1 n2]);
                v1y = repmat(line1(:,5), [1 n2]);
                v1z = repmat(line1(:,6), [1 n2]);
                p1x = repmat(line1(:,1), [1 n2]);
                p1y = repmat(line1(:,2), [1 n2]);
                p1z = repmat(line1(:,3), [1 n2]);
                v2x = repmat(line2(:,4)', [n1 1]);
                v2y = repmat(line2(:,5)', [n1 1]);
                v2z = repmat(line2(:,6)', [n1 1]);
                p2x = repmat(line2(:,1)', [n1 1]);
                p2y = repmat(line2(:,2)', [n1 1]);
                p2z = repmat(line2(:,3)', [n1 1]);
                vcross = cross([v1x(:) v1y(:) v1z(:)], [v2x(:) v2y(:) v2z(:)]);
                num = ([p1x(:) p1y(:) p1z(:)] - [p2x(:) p2y(:) p2z(:)]) .* vcross;
                t1 = sum(num,2);
                d = abs(t1) ./ (modelisation_lidar.vectorNorm3d(vcross) + eps);
                
                d = reshape(d, n1, n2);
            else
                if n1 ~= n2
                    error('geom3d:distanceLines3d:IllegalInputArgument', ...
                        'lorsque les points de sortie sont demandés, le nombre de lignes doit être identique');
                end
                
                p1 = line1(:, 1:3);
                p2 = line2(:, 1:3);
                dp = p2 - p1;
                v1 = line1(:, 4:6);
                v2 = line2(:, 4:6);
                
                vcross = cross(v1, v2, 2);
                num = dp .* vcross;
                t1 = sum(num, 2);
                d = abs(t1) ./ (modelisation_lidar.vectorNorm3d(vcross) + eps);
                
                a = dot(v1, v1, 2);
                b = dot(v1, v2, 2);
                e = dot(v2, v2, 2);
                den = a.*e - b.*b; 
                
                
                r = line1(:,1:3) - line2(:,1:3);
                
                
                c = dot(v1, r, 2);
                f = dot(v2, r, 2);
                s = (b .* f - c .* e) ./ den;
                t = (a .* f - c .* b) ./ den;
                
                pt1 = line1(:,1:3) + v1 .* s;
                pt2 = line2(:,1:3) + v2 .* t;
            end
        end
        
        function line = createLine3d(varargin)
            
            if length(varargin)==1
                error('Wrong number of arguments in ''createLine'' ');
                
            elseif length(varargin)==2
                
                v1 = varargin{1};
                v2 = varargin{2};
                if size(v1, 2)==3
                    
                    line = [v1(:,1) v1(:,2) v1(:,3) v2(:,1)-v1(:,1) v2(:,2)-v1(:,2) v2(:,3)-v1(:,3)];
                elseif size(v1, 2)==1
                    
                    theta = varargin{1};
                    phi = varargin{2};
                    sit = sin(theta);
                    p0 = zeros([size(theta, 1) 3]);
                    
                    line = [p0 cos(phi)*sit sin(phi)*sit cos(theta)];
                end
                
            elseif length(varargin)==3
                
                p0      = varargin{1};
                theta   = varargin{2};
                phi     = varargin{3};
                
                if size(p0, 2)~=3
                    error('first argument should be a 3D point');
                end
                
                sit = sin(theta);
                line = [p0 cos(phi)*sit sin(phi)*sit cos(theta)];
                
            elseif length(varargin)==4
                p0 = varargin{1};
                dx = varargin{2};
                dy = varargin{3};
                dz = varargin{4};
                
                if size(p0, 2)~=3
                    error('first argument should be a 3D point');
                end
                
                line = [p0 dx dy dz];
            elseif length(varargin)==5
                
                x0      = varargin{1};
                y0      = varargin{1};
                z0      = varargin{1};
                theta   = varargin{2};
                phi     = varargin{3};
                
                sit = sin(theta);
                line = [x0 y0 z0 cos(phi)*sit sin(phi)*sit cos(theta)];
            elseif length(varargin)==6
                
                x0 = varargin{1};
                y0 = varargin{2};
                z0 = varargin{3};
                dx = varargin{4};
                dy = varargin{5};
                dz = varargin{6};
                line = [x0 y0 z0 dx dy dz];
            end
        end
       
        function dist = distancePoints3d(p1, p2, varargin)
            
            norm = 2;
            if length(varargin)==1
                norm = varargin{1};
            end
            
            ptsDiff = bsxfun(@minus, p2, p1);
            
            switch(norm)
                case 1
                    dist = sum(abs(ptsDiff),2);
                case 2
                    dist = modelisation_lidar.vectorNorm3d(ptsDiff);
                case Inf
                    dist = max(abs(ptsDiff), [], 2);
                otherwise
                    dist = power(sum(power(ptsDiff, norm),2), 1/norm);
            end
        end
    
        function n = vectorNorm3d(v)
            n = sqrt(sum(v.*v, 2));
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            zlabel(app.UIAxes, 'Z');
            rotate3d(app.UIAxes,'on');
        end

        % Button pushed function: IMPORTERUNNUAGEDEPOINTSButton
        function IMPORTERUNNUAGEDEPOINTSButtonPushed(app, event)
            global nuage_plot
            global nuage
            global FileName
            global PathName
            
            [FileName,PathName] = uigetfile({'*.asc'}, 'Veuillez choisir un fichier');
            
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Chargement de vos données', 'Indeterminate','on');
            
            if ~FileName
                uialert(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Veuillez choisir un fichier', 'Erreur');
            elseif ~endsWith(FileName, 'asc')
                uialert(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Fichier non valide', 'Erreur');
            else
                app.CheminEditField.Value = strcat(PathName, FileName);
                nuage = importdata(strcat(PathName, FileName));
                
                hold(app.UIAxes, 'on');
                nuage_plot = plot3(app.UIAxes, nuage(:, 1), nuage(:, 2), nuage(:, 3), '.', 'Color', [221, 146, 88]/255, 'MarkerSize', 10);
            end
            
            close(p);
        end

        % Button pushed function: EXCUTERButton
        function executerSegRansa(app, event)
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Ségmentation RANSAC en cours ...', 'Indeterminate','on');
            
            global nuage_plot
            global nuage
            global nuage_tranche_plot
            global nuage_tranches
            
            nuage_tranches = cell(0);
            
            while 1
                 % prendre les indices des points qui forment le meilleur plan à l'aide de RANSAC
                 [~, ~, id] = modelisation_lidar.ransacfitplane(nuage', app.TOLRANCEKnob.Value);
                 
                 % test de sortie de la boucle while (Tolérance)
                 if length(id) < str2double(app.NOMBREMINIMUMDEPOINTSKnob.Value)
                     break;
                 end
                 
                 % affichage de la tranche
                 delete(nuage_tranche_plot);
                 
                 % aplatir les points détéctés
                 pts_plats = modelisation_lidar.aplatir_points_barycentre(nuage(id, :));
                 
                 % sauvegarder la tranche (points plats) dans la cellule nuage_tranches
                 nuage_tranches{length(nuage_tranches)+1} = pts_plats;
                 
                 % extraire le reste du nuage sans la tranche
                 nuage = setdiff(nuage, nuage(id, :), 'rows');
                 
                 % afficher les plans détectés en noir lors de l'execution
                 nuage_tranche_plot = plot3(app.UIAxes, pts_plats(:, 1), pts_plats(:, 2), pts_plats(:, 3),'k.','MarkerSize', 20);
                 
                 % afficher le nombre de plans détectés
                 app.Label.Text = sprintf('NOMBRE DE PLAN DÉTECTÉS : %d', length(nuage_tranches));
                 drawnow;
                 
                 % faire une mise à jour de plot du nuage
                 delete(nuage_plot);
                 nuage_plot = plot3(app.UIAxes, nuage(:,1), nuage(:,2), nuage(:,3), '.', 'Color', [221, 146, 88]/255, 'MarkerSize', 10);
                 
            end
            
            % affichage des plans détectés
            delete(nuage_plot);
            delete(nuage_tranche_plot);
            
            cmap = hsv(10);
            
            t = '';

            for i = 1:length(nuage_tranches)
                plot3(app.UIAxes, nuage_tranches{i}(:, 1), nuage_tranches{i}(:, 2), nuage_tranches{i}(:, 3), '.','Color',cmap(i,:), 'MarkerSize', 12, 'DisplayName',sprintf('PLAN %d', i));
                t = strcat(t, sprintf('\nPlan %d contient %d points', i, length(nuage_tranches{i})));
            end
            
            app.LabelPlansPoints.Text = t;
            legend(app.UIAxes);
            
            close(p);
        end

        % Button pushed function: SegRansacAnnulerButton
        function SegRansacAnnulerButtonPushed(app, event)
            global FileName
            global PathName
            global nuage
            global nuage_plot
            
            % reinitialisation
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Annulation de la ségmentation RANSAC en cours ...', 'Indeterminate','on');
            
            cla(app.UIAxes);
            
            app.Label.Text = '';
            app.LabelPlansPoints.Text = '';
            
            legend(app.UIAxes, "off");
            
            nuage = importdata(strcat(PathName, FileName));
            nuage_plot = plot3(app.UIAxes, nuage(:, 1), nuage(:, 2), nuage(:, 3), '.', 'Color', [221, 146, 88]/255, 'MarkerSize', 10);
            
            close(p);
        end

        % Button pushed function: ExecDetectContours
        function ExecDetectContoursButtonPushed(app, event)
            global nuage_tranches
            global nuage_tranches_contours
            
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Détection des contours par alphaShape en cours ...', 'Indeterminate','on');
            
            nuage_tranches_contours = cell(0);
            cla(app.UIAxes);
            
            cmap = hsv(10);
            
            % pour chaque tranche nu nugae on va extraire les points de contour à l'aide de alphaShape et boundaryFacets
            for i = 1:length(nuage_tranches)
                [~, xyz] = boundaryFacets(alphaShape(nuage_tranches{i}(:, 1), nuage_tranches{i}(:, 2), nuage_tranches{i}(:, 3), Inf));
               
                plot3(app.UIAxes, xyz(:, 1), xyz(:, 2), xyz(:, 3), '.','Color',cmap(i,:), 'MarkerSize', 12, 'DisplayName',sprintf('Contour du plan %d', i));
                nuage_tranches_contours{i} = xyz;
            end
            
            legend(app.UIAxes);
            close(p)
        end

        % Button pushed function: AnnulerDetectContours
        function AnnulerDetectContoursButtonPushed(app, event)
            global nuage_tranches
            
            % reinitialisation
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Annulation des contours d''alphaShape en cours ...', 'Indeterminate','on');
            
            cla(app.UIAxes);
            cmap = hsv(10);
            
            for i = 1:length(nuage_tranches)
                plot3(app.UIAxes, nuage_tranches{i}(:, 1), nuage_tranches{i}(:, 2), nuage_tranches{i}(:, 3), '.','Color',cmap(i,:), 'MarkerSize', 12, 'DisplayName',sprintf('PLAN %d', i));
            end
            
            legend(app.UIAxes);
            
            close(p);
        end

        % Button pushed function: AnnulerDetectIntersection, 
        % ExecDetectDroites
        function ExecDetectDroitesButtonPushed(app, event)
            global nuage_tranches_contours
            global pts_deb_fin_droites
           
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Détection des droite par RANSAC en cours ...', 'Indeterminate','on');
            
            cla(app.UIAxes);
            legend(app.UIAxes, "off");
            cmap = hsv(10);
            
            pts_deb_fin_droites = cell(0, 2);
            
            % pour chaque contour on va détecter les droites à l'aide de ransac appliqué au droites
            for i = 1:length(nuage_tranches_contours)
            
                d = cell(0);
                f = cell(0);
                contour = nuage_tranches_contours{i};
            
                while 1
                    [~, L, ind] = modelisation_lidar.ransacfitline(contour', app.ToleranceDroite.Value);
                    
                    if length(ind) < str2double(app.MinPtsDroite.Value)
                        break;
                    end
                    
                    d{length(d)+1} = L(:, 1)';
                    f{length(f)+1} = L(:, 2)';
                    
                    plot3(app.UIAxes, contour(ind, 1), contour(ind, 2), contour(ind, 3), '-','Color',cmap(i,:), 'LineWidth', 3);
                    contour = setdiff(contour, contour(ind, :), 'rows');
                end
                
                pts_deb_fin_droites{size(pts_deb_fin_droites, 1)+1, 1} = d;
                pts_deb_fin_droites{size(pts_deb_fin_droites, 1), 2} = f;
                
            end
            
            close(p);
        end

        % Button pushed function: AnnulerDetectDroites
        function AnnulerDetectDroitesButtonPushed(app, event)
            global nuage_tranches
            global nuage_tranches_contours
            
            % reinitialisation
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Annulation des droites en cours ...', 'Indeterminate','on');
            nuage_tranches_contours = cell(0);
            cla(app.UIAxes);
            
            cmap = hsv(10);
            
            for i = 1:length(nuage_tranches)
                [~, xyz] = boundaryFacets(alphaShape(nuage_tranches{i}(:, 1), nuage_tranches{i}(:, 2), nuage_tranches{i}(:, 3), Inf));
                
                plot3(app.UIAxes, xyz(:, 1), xyz(:, 2), xyz(:, 3), '.','Color',cmap(i,:), 'MarkerSize', 12, 'DisplayName',sprintf('Contour du plan %d', i));
                nuage_tranches_contours{i} = xyz;
            end
            
            legend(app.UIAxes);
            
            close(p);
        end

        % Button pushed function: ExecDetectIntersection
        function ExecDetectIntersectionButtonPushed(app, event)
            global pts_deb_fin_droites
            global pts_polygones
            
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Détection des points d''intersection en cours ...', 'Indeterminate','on');
            
            pts_polygones = cell(1, length(pts_deb_fin_droites));
            
            for i = 1:length(pts_deb_fin_droites)
                d = pts_deb_fin_droites{i, 1};
                f = pts_deb_fin_droites{i, 2};
                
                pts_polygone = cell(0);
                
                for k = 1:length(d)
                    for j = 1:length(d)
                        if j ~= k
                            
                            [dist, pt1, ~] = modelisation_lidar.distanceLines3d(modelisation_lidar.createLine3d(d{k}, f{k}), modelisation_lidar.createLine3d(d{j}, f{j}));
                            if dist < app.ToleranceIntersection.Value
                                
                                d1 = modelisation_lidar.distancePoints3d(pt1, d{k});
                                d2 = modelisation_lidar.distancePoints3d(pt1, f{k});
                                
                                if d1 < d2
                                    plot3(app.UIAxes, d{k}(1), d{k}(2), d{k}(3), 'k.', 'MarkerSize', 20);
                                    pts_polygone{length(pts_polygone)+1} = d{k}; 
                                else
                                    plot3(app.UIAxes, f{k}(1), f{k}(2), f{k}(3), 'k.', 'MarkerSize', 20);
                                    pts_polygone{length(pts_polygone)+1} = f{k};
                                end
                                drawnow;
                            end
                        end
                    end
                end
                
                pts_polygones{1, i} = pts_polygone';
                
            end
            
            close(p);
            
        end

        % Callback function
        function AnnulerDetectIntersectionButtonPushed(app, event)

        end

        % Button pushed function: ExecConstPoly
        function ExecConstPolyButtonPushed(app, event)
            global pts_polygones
            
            p = uiprogressdlg(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure,'Title','Construction des polygones en cours ...', 'Indeterminate','on');
            
            cla(app.UIAxes);
            cmap = hsv(10);
            
            for i = 1:length(pts_polygones)
                pts_polygone = pts_polygones{i};
                pts_polygone = cell2mat(pts_polygone);
              
                x = [pts_polygone(:, 1); pts_polygone(1, 1)];
                y = [pts_polygone(:, 2); pts_polygone(1, 2)];
                z = [pts_polygone(:, 3); pts_polygone(1, 3)];
                
                plot3(app.UIAxes, x, y, z, '-','Color',cmap(i,:), 'LineWidth', 2);
            end
            
            close(p);
            
        end

        % Button pushed function: AnnulerConstPoly
        function AnnulerConstPolyButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MODLISATIONLIDARBAKKACHJAMALIUIFigure = uifigure;
            app.MODLISATIONLIDARBAKKACHJAMALIUIFigure.Name = 'MODÉLISATION LIDAR - BAKKACH & JAMALI';
            app.TabGroup = uitabgroup(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure);
            app.SGMENTATIONRANSACTab = uitab(app.TabGroup);
            app.SGMENTATIONRANSACTab.Title = 'SÉGMENTATION RANSAC';
            app.TOLRANCEKnobLabel = uilabel(app.SGMENTATIONRANSACTab);
            app.TOLRANCEKnobLabel.Text = 'TOLÉRANCE';
            app.TOLRANCEKnob = uiknob(app.SGMENTATIONRANSACTab, 'continuous');
            app.TOLRANCEKnob.Limits = [0.01 0.09];
            app.TOLRANCEKnob.MajorTicks = [0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09];
            app.TOLRANCEKnob.Value = 0.03;
            app.NOMBREMINIMUMDEPOINTSKnobLabel = uilabel(app.SGMENTATIONRANSACTab);
            app.NOMBREMINIMUMDEPOINTSKnobLabel.Text = 'NOMBRE MINIMUM DE POINTS';
            app.NOMBREMINIMUMDEPOINTSKnob = uiknob(app.SGMENTATIONRANSACTab, 'discrete');
            app.NOMBREMINIMUMDEPOINTSKnob.Items = {'800', '1000', '1200', '1400'};
            app.NOMBREMINIMUMDEPOINTSKnob.Value = '1000';
            app.ACCEPTABLEPOURUNPLANLabel = uilabel(app.SGMENTATIONRANSACTab);
            app.ACCEPTABLEPOURUNPLANLabel.Text = ' ACCEPTABLE POUR UN PLAN';
            app.EXCUTERButton = uibutton(app.SGMENTATIONRANSACTab, 'push');
            app.EXCUTERButton.ButtonPushedFcn = createCallbackFcn(app, @executerSegRansa, true);
            app.EXCUTERButton.Text = 'EXÉCUTER';
            app.Label = uilabel(app.SGMENTATIONRANSACTab);
            app.Label.Text = '';
            app.LabelPlansPoints = uilabel(app.SGMENTATIONRANSACTab);
            app.LabelPlansPoints.Text = '';
            app.SegRansacAnnulerButton = uibutton(app.SGMENTATIONRANSACTab, 'push');
            app.SegRansacAnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @SegRansacAnnulerButtonPushed, true);
            app.SegRansacAnnulerButton.Text = 'ANNULER';
            app.DTCTIONDESCONTOURSTab = uitab(app.TabGroup);
            app.DTCTIONDESCONTOURSTab.Title = 'DÉTÉCTION DES CONTOURS';
            app.ExecDetectContours = uibutton(app.DTCTIONDESCONTOURSTab, 'push');
            app.ExecDetectContours.ButtonPushedFcn = createCallbackFcn(app, @ExecDetectContoursButtonPushed, true);
            app.ExecDetectContours.Text = 'EXÉCUTER';
            app.AnnulerDetectContours = uibutton(app.DTCTIONDESCONTOURSTab, 'push');
            app.AnnulerDetectContours.ButtonPushedFcn = createCallbackFcn(app, @AnnulerDetectContoursButtonPushed, true);
            app.AnnulerDetectContours.Text = 'ANNULER';
            app.DTCTIONDESDROITESTab = uitab(app.TabGroup);
            app.DTCTIONDESDROITESTab.Title = 'DÉTÉCTION DES DROITES';
            app.ExecDetectDroites = uibutton(app.DTCTIONDESDROITESTab, 'push');
            app.ExecDetectDroites.ButtonPushedFcn = createCallbackFcn(app, @ExecDetectDroitesButtonPushed, true);
            app.ExecDetectDroites.Text = 'EXÉCUTER';
            app.ACCEPTABLEPOURUNEDROITELabel = uilabel(app.DTCTIONDESDROITESTab);
            app.ACCEPTABLEPOURUNEDROITELabel.Text = ' ACCEPTABLE POUR UNE DROITE';
            app.TOLRANCEKnob_2Label = uilabel(app.DTCTIONDESDROITESTab);
            app.TOLRANCEKnob_2Label.Text = 'TOLÉRANCE';
            app.ToleranceDroite = uiknob(app.DTCTIONDESDROITESTab, 'continuous');
            app.ToleranceDroite.Limits = [0.01 0.09];
            app.ToleranceDroite.MajorTicks = [0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09];
            app.ToleranceDroite.Value = 0.022;
            app.NOMBREMINIMUMDEPOINTSKnob_2Label = uilabel(app.DTCTIONDESDROITESTab);
            app.NOMBREMINIMUMDEPOINTSKnob_2Label.Text = 'NOMBRE MINIMUM DE POINTS';
            app.MinPtsDroite = uiknob(app.DTCTIONDESDROITESTab, 'discrete');
            app.MinPtsDroite.Items = {'3', '4', '5', '6', '7', '8', '9', '10'};
            app.MinPtsDroite.Value = '5';
            app.AnnulerDetectDroites = uibutton(app.DTCTIONDESDROITESTab, 'push');
            app.AnnulerDetectDroites.ButtonPushedFcn = createCallbackFcn(app, @AnnulerDetectDroitesButtonPushed, true);
            app.AnnulerDetectDroites.Text = 'ANNULER';
            app.POINTSDINTERSECTIONSTab = uitab(app.TabGroup);
            app.POINTSDINTERSECTIONSTab.Title = 'POINTS D''INTERSECTIONS';
            app.TOLRANCEKnob_2Label_2 = uilabel(app.POINTSDINTERSECTIONSTab);
            app.TOLRANCEKnob_2Label_2.Text = 'TOLÉRANCE';
            app.ToleranceIntersection = uiknob(app.POINTSDINTERSECTIONSTab, 'continuous');
            app.ToleranceIntersection.Limits = [1e-12 1e-10];
            app.ToleranceIntersection.Value = 6.7e-11;
            app.ExecDetectIntersection = uibutton(app.POINTSDINTERSECTIONSTab, 'push');
            app.ExecDetectIntersection.ButtonPushedFcn = createCallbackFcn(app, @ExecDetectIntersectionButtonPushed, true);
            app.ExecDetectIntersection.Text = 'EXÉCUTER';
            app.AnnulerDetectIntersection = uibutton(app.POINTSDINTERSECTIONSTab, 'push');
            app.AnnulerDetectIntersection.ButtonPushedFcn = createCallbackFcn(app, @ExecDetectDroitesButtonPushed, true);
            app.AnnulerDetectIntersection.Text = 'ANNULER';
            app.CONSTRUCTIONDESPOLYGONESTab = uitab(app.TabGroup);
            app.CONSTRUCTIONDESPOLYGONESTab.Title = 'CONSTRUCTION DES POLYGONES';
            app.ExecConstPoly = uibutton(app.CONSTRUCTIONDESPOLYGONESTab, 'push');
            app.ExecConstPoly.ButtonPushedFcn = createCallbackFcn(app, @ExecConstPolyButtonPushed, true);
            app.ExecConstPoly.Text = 'EXÉCUTER';
            app.AnnulerConstPoly = uibutton(app.CONSTRUCTIONDESPOLYGONESTab, 'push');
            app.AnnulerConstPoly.ButtonPushedFcn = createCallbackFcn(app, @AnnulerConstPolyButtonPushed, true);
            app.AnnulerConstPoly.Text = 'ANNULER';
            app.IMPORTERUNNUAGEDEPOINTSButton = uibutton(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure, 'push');
            app.IMPORTERUNNUAGEDEPOINTSButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTERUNNUAGEDEPOINTSButtonPushed, true);
            app.IMPORTERUNNUAGEDEPOINTSButton.Text = 'IMPORTER UN NUAGE DE POINTS';
            app.CheminEditField = uieditfield(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure, 'text');
            app.CheminEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure);
            title(app.UIAxes, 'GRAPHE')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = modelisation_lidar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MODLISATIONLIDARBAKKACHJAMALIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MODLISATIONLIDARBAKKACHJAMALIUIFigure)
        end
    end
end